classdef Camset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamsetMain                      matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        ChooseCameraPanel               matlab.ui.container.Panel
        CameraDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        ConnectButton                   matlab.ui.control.Button
        ConnectingLamp                  matlab.ui.control.Lamp
        CameraBrowserButton             matlab.ui.control.Button
        ControlPanel                    matlab.ui.container.Panel
        InitializeButton                matlab.ui.control.Button
        LoadSettingButton               matlab.ui.control.Button
        SaveSettingButton               matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        MainPanel                       matlab.ui.container.Panel
        PreviewPanel                    matlab.ui.container.Panel
        StartPreviewButton              matlab.ui.control.Button
        StopPreviewButton               matlab.ui.control.Button
        AcquirePanel                    matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TriggerButton                   matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        ImageShow                       matlab.ui.control.UIAxes
        ROIPanel                        matlab.ui.container.Panel
        XcenterSpinnerPCOLabel          matlab.ui.control.Label
        XcenterSpinner                  matlab.ui.control.Spinner
        WidthSpinnerPCOLabel            matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        YcenterSpinnerPCOLabel          matlab.ui.control.Label
        YcenterSpinner                  matlab.ui.control.Spinner
        HeightSpinnerPCOLabel           matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        ROIButton                       matlab.ui.control.Button
        DefaulROItButton                matlab.ui.control.Button
        TriggerPanel                    matlab.ui.container.Panel
        TriggerTypeDropDownPCOLabel     matlab.ui.control.Label
        TriggerTypeDropDown             matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        NumberoftriggerSpinnerPCOLabel  matlab.ui.control.Label
        NumberoftriggerSpinner          matlab.ui.control.Spinner
        FrameperTriggerSpinnerPCOLabel  matlab.ui.control.Label
        FrameperTriggerSpinner          matlab.ui.control.Spinner
        ConsitionDropDownLabel          matlab.ui.control.Label
        ConsitionDropDown               matlab.ui.control.DropDown
        CameraSettingPanel              matlab.ui.container.Panel
        GeneralSettingButton            matlab.ui.control.Button
        Status                          matlab.ui.control.EditField
    end

    methods (Access = private)

        % Size changed function: CamsetMain
        function CamsetMainSizeChanged(app, event)
            position = app.CamsetMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 125 position(3) position(4)-125];
            app.MainPanel.Position = [0 22 position(3) position(4)-125-22];
        end

        % Size changed function: HomeTab
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            app.ChooseCameraPanel.Position = [0 0 0.4*position(3) position(4)];
            app.ControlPanel.Position = [0.4*position(3) 0 0.4*position(3) position(4)];
        end

        % Size changed function: MainPanel
        function MainPanelSizeChanged(app, event)
            position = app.MainPanel.Position;
            if ~isfield(app,'PreviewPanel')
                app.PreviewPanel.Position = [0 0 0.3*position(3) 70];
            end
            if ~isfield(app,'ImageShow')
                app.ImageShow.Position = [0 70 position(3)-205 position(4)-70];
            end
            if isfield(app,'AcquirePanel')
                app.AcquirePanel.Position = [0.3*position(4) 0 0.4*position(4) 70];
            end
            if isfield(app,'CameraSettingPanel')
                app.CameraSettingPanel.Position = [0.7*position(3) 0 0.3*position(3) 70];
            end
            if isfield(app,'ROIPanel')
                app.ROIPanel.Position = [position(3)-205 position(4)/2 205 position(4)/2 ];
            end
            if isfield(app,'TriggerPanel')
                app.TriggerPanel.Position = [position(3)-205 0 2015 position(4)/2];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamsetMain = uifigure;
            app.CamsetMain.Name = 'UI Figure';
            app.CamsetMain.SizeChangedFcn = createCallbackFcn(app, @CamsetMainSizeChanged, true);
            setAutoResize(app, app.CamsetMain, true)
            app.MainTabGroup = uitabgroup(app.CamsetMain);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.SizeChangedFcn = createCallbackFcn(app, @HomeTabSizeChanged, true);
            app.HomeTab.Title = 'Home';
            app.HomeTab.Units = 'pixels';
            app.ChooseCameraPanel = uipanel(app.HomeTab);
            app.ChooseCameraPanel.Title = 'Choose camera';
            app.CameraDropDownLabel = uilabel(app.ChooseCameraPanel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.ChooseCameraPanel);
            app.ConnectButton = uibutton(app.ChooseCameraPanel, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConnectingLamp = uilamp(app.ChooseCameraPanel);
            app.CameraBrowserButton = uibutton(app.ChooseCameraPanel, 'push');
            app.CameraBrowserButton.Text = {'Camera'; 'Browser'};
            app.ControlPanel = uipanel(app.HomeTab);
            app.ControlPanel.Title = 'Control';
            app.InitializeButton = uibutton(app.ControlPanel, 'push');
            app.InitializeButton.Text = 'Initialize';
            app.LoadSettingButton = uibutton(app.ControlPanel, 'push');
            app.LoadSettingButton.Enable = 'off';
            app.LoadSettingButton.Text = 'Load Setting';
            app.SaveSettingButton = uibutton(app.ControlPanel, 'push');
            app.SaveSettingButton.Enable = 'off';
            app.SaveSettingButton.Text = 'Save Setting';
            app.DefaultButton = uibutton(app.ControlPanel, 'push');
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.Tab2 = uitab(app.MainTabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Units = 'pixels';
            app.MainPanel = uipanel(app.CamsetMain);
            app.MainPanel.SizeChangedFcn = createCallbackFcn(app, @MainPanelSizeChanged, true);
            app.PreviewPanel = uipanel(app.MainPanel);
            app.StartPreviewButton = uibutton(app.PreviewPanel, 'push');
            app.StartPreviewButton.Text = 'Start Preview';
            app.StopPreviewButton = uibutton(app.PreviewPanel, 'push');
            app.StopPreviewButton.Text = 'Stop Preview';
            app.AcquirePanel = uipanel(app.MainPanel);
            app.StartButton = uibutton(app.AcquirePanel, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.AcquirePanel, 'push');
            app.StopButton.Text = 'Stop';
            app.TriggerButton = uibutton(app.AcquirePanel, 'push');
            app.TriggerButton.Text = 'Trigger';
            app.ExportButton = uibutton(app.AcquirePanel, 'push');
            app.ExportButton.Text = 'Export...';
            app.ImageShow = uiaxes(app.MainPanel);
            app.ROIPanel = uipanel(app.MainPanel);
            app.ROIPanel.Title = 'ROI';
            app.XcenterSpinnerPCOLabel = uilabel(app.ROIPanel);
            app.XcenterSpinnerPCOLabel.Text = 'X center';
            app.XcenterSpinner = uispinner(app.ROIPanel);
            app.XcenterSpinner.Limits = [1 Inf];
            app.XcenterSpinner.ValueDisplayFormat = '%.1f';
            app.XcenterSpinner.Enable = 'off';
            app.XcenterSpinner.Value = 200;
            app.WidthSpinnerPCOLabel = uilabel(app.ROIPanel);
            app.WidthSpinnerPCOLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.ROIPanel);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.ValueDisplayFormat = '%.1f';
            app.WidthSpinner.Enable = 'off';
            app.WidthSpinner.Value = 50;
            app.YcenterSpinnerPCOLabel = uilabel(app.ROIPanel);
            app.YcenterSpinnerPCOLabel.Text = 'Y center';
            app.YcenterSpinner = uispinner(app.ROIPanel);
            app.YcenterSpinner.Limits = [1 Inf];
            app.YcenterSpinner.ValueDisplayFormat = '%.1f';
            app.YcenterSpinner.Enable = 'off';
            app.YcenterSpinner.Value = 200;
            app.HeightSpinnerPCOLabel = uilabel(app.ROIPanel);
            app.HeightSpinnerPCOLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.ROIPanel);
            app.HeightSpinner.Limits = [1 Inf];
            app.HeightSpinner.ValueDisplayFormat = '%.1f';
            app.HeightSpinner.Enable = 'off';
            app.HeightSpinner.Value = 50;
            app.ROIButton = uibutton(app.ROIPanel, 'push');
            app.ROIButton.Enable = 'off';
            app.ROIButton.Text = 'ROI';
            app.DefaulROItButton = uibutton(app.ROIPanel, 'push');
            app.DefaulROItButton.Enable = 'off';
            app.DefaulROItButton.Text = 'Default';
            app.TriggerPanel = uipanel(app.MainPanel);
            app.TriggerPanel.Title = 'Trigger';
            app.TriggerTypeDropDownPCOLabel = uilabel(app.TriggerPanel);
            app.TriggerTypeDropDownPCOLabel.Text = 'Trigger Type:';
            app.TriggerTypeDropDown = uidropdown(app.TriggerPanel);
            app.TriggerTypeDropDown.Items = {'immediate', 'manual', 'hardware'};
            app.TriggerTypeDropDown.Enable = 'off';
            app.TriggerTypeDropDown.Value = 'immediate';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.TriggerPanel);
            app.SourceDropDown.Items = {'none', 'ExternExposureStart'};
            app.SourceDropDown.Enable = 'off';
            app.SourceDropDown.Value = 'none';
            app.NumberoftriggerSpinnerPCOLabel = uilabel(app.TriggerPanel);
            app.NumberoftriggerSpinnerPCOLabel.Text = 'Number of trigger';
            app.NumberoftriggerSpinner = uispinner(app.TriggerPanel);
            app.NumberoftriggerSpinner.Limits = [1 Inf];
            app.NumberoftriggerSpinner.Enable = 'off';
            app.NumberoftriggerSpinner.Value = 1;
            app.FrameperTriggerSpinnerPCOLabel = uilabel(app.TriggerPanel);
            app.FrameperTriggerSpinnerPCOLabel.Text = 'Frame per Trigger';
            app.FrameperTriggerSpinner = uispinner(app.TriggerPanel);
            app.FrameperTriggerSpinner.Limits = [1 Inf];
            app.FrameperTriggerSpinner.Enable = 'off';
            app.FrameperTriggerSpinner.Value = 1;
            app.ConsitionDropDownLabel = uilabel(app.TriggerPanel);
            app.ConsitionDropDownLabel.Text = 'Consition';
            app.ConsitionDropDown = uidropdown(app.TriggerPanel);
            app.ConsitionDropDown.Items = {'None', ''};
            app.ConsitionDropDown.Value = 'None';
            app.CameraSettingPanel = uipanel(app.MainPanel);
            app.GeneralSettingButton = uibutton(app.CameraSettingPanel, 'push');
            app.GeneralSettingButton.Text = {'General'; 'Setting'};
            app.Status = uieditfield(app.CamsetMain, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Camset()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamsetMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamsetMain)
        end
    end
end