classdef Vuima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VuimaMain             matlab.ui.Figure
        MainTabGroup          matlab.ui.container.TabGroup
        HomeTab               matlab.ui.container.Tab
        LinkingPanel          matlab.ui.container.Panel
        imastDropDownLabel    matlab.ui.control.Label
        imastDropDown         matlab.ui.control.DropDown
        LinkingLamp           matlab.ui.control.Lamp
        LinkButton            matlab.ui.control.Button
        SavingPanel           matlab.ui.container.Panel
        ViewTab               matlab.ui.container.Tab
        FilePanel             matlab.ui.container.Panel
        FolderEditFieldLabel  matlab.ui.control.Label
        FolderEditField       matlab.ui.control.EditField
        BrowserButton         matlab.ui.control.Button
        FittingData           matlab.ui.control.Table
        Status                matlab.ui.control.EditField
        FittingPanel          matlab.ui.container.Panel
        ImageShow             matlab.ui.control.UIAxes
        fittingY              matlab.ui.control.UIAxes
        fittingX              matlab.ui.control.UIAxes
        FittingSettingPanel   matlab.ui.container.Panel
        Auto                  matlab.ui.control.CheckBox
        FitButton             matlab.ui.control.Button
        FittingModeDropDown   matlab.ui.control.DropDown
        FittingSettingButton  matlab.ui.control.Button
        StopButton            matlab.ui.control.Button
    end


    properties (Access = public)
        camima % main camima handle
        imast % image stream
        curimage %present image data, used to show and do fitting
        Equation % fit equation
        opt % fit options
    end
    
    methods (Access = public)
        
        function [fitresult, gof] = dofit(~,XData, YData, ZData, equation, opts)
            %% Fit
            [xData, yData, zData] = prepareSurfaceData(XData, YData, ZData);
            
            % Set up fittype and options.
            ft = fittype(equation{1}, 'independent', {'x', 'y'}, 'dependent', 'z' );
                                    
            % Fit model to data.
            [fitresult, gof] = fit( [xData, yData], zData, ft, opts );
        end
        

        function [Xdata, Ydata, Zdata, peak, xc, yc, xs, ys] = image2data(~, data)
            %data = app.curimage;
            [xs,ys] = size(data);           
            Zdata = data(:)';         
            Xdata = repmat(1:xs,1,ys);
            Ydata = repelem(1:ys,xs);
            % peak and canter more info from preprocess
            [peak,peakPosition] = max(data(:));
            [xc,yc] = ind2sub(size(data),peakPosition);
        end
        
        function fit(app)
            %do fitting
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            [fitresult, gof] = dofit(app, XData, YData, ZData, app.Equation, app.opt);
            app.Status.Value = 'Fitting done!';
            
            %reconstruct function and data for showing
            coNumber = numcoeffs(fitresult);
            coName = coeffnames(fitresult);
            coValue = coeffvalues(fitresult);
            
            % show fitting result
            app.FittingData.Data = [coName num2cell(coValue')];
            
            %construct coeff string
            costr = coName{1};
            if coNumber > 1
                for i = 2:coNumber
                    costr = [costr ',' coName{i}];
                end
            end
            %build function for plot
            fy = str2func(vectorize(['@(x) @(' costr ') @(y)' formula(fitresult)]));
            fx = str2func(vectorize(['@(y) @(' costr ') @(x)' formula(fitresult)]));
            
            %plot x-direction 
            
            fytemp = fy(xc);
            arg = num2cell(coValue);
            f = fytemp(arg{:});
            
            app.fittingX.XLim = [0.5 ys+0.5];
            plot(app.fittingX,1:ys,f(1:ys),1:ys,app.curimage(xc,:));


            %plot y-direction
            fxtemp = fx(yc);
            arg = num2cell(coValue);
            f = fxtemp(arg{:});
            
            app.fittingY.YLim = [0.5 xs+0.5];
            app.fittingY.YDir = 'reverse';
            plot(app.fittingY,f(1:xs),1:xs,app.curimage(:,yc)',1:xs);
        end
        
    end


    methods (Access = private)

        % Size changed function: VuimaMain
        function VuimaMainSizeChanged(app, event)
            position = app.VuimaMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 position(4)-125 position(3) 125];
            app.FittingData.Position = [0 22 150 position(4)-22-125];
            app.FittingPanel.Position = [150 22 position(3)-150 position(4)-22-125];
        end

        % Size changed function: HomeTab
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            app.LinkingPanel.Position = [0 0 300 position(4)];
            app.SavingPanel.Position = [300 0 300 position(4)];
        end

        % Size changed function: ViewTab
        function ViewTabSizeChanged(app, event)
            position = app.ViewTab.Position;
            app.FilePanel.Position = [0 0 300 position(4)];
        end

        % Size changed function: FittingPanel
        function FittingPanelSizeChanged(app, event)
            position = app.FittingPanel.Position;
            if ~isempty(app.ImageShow)
                app.ImageShow.Position = [0 130 position(3)-130 position(4)-130];
            end
            
            if ~isempty(app.fittingX)
                app.fittingX.Position = [0 0 position(3)-130 130];
            end
            if ~isempty(app.fittingY)
                app.fittingY.Position = [position(3)-130 130 130 position(4)-130];
            end
            if ~isempty(app.FittingSettingPanel)
                app.FittingSettingPanel.Position = [position(3)-130 0 130 130];
            end
        end

        % Button pushed function: LinkButton
        function LinkButtonPushed(app, event)
            Imast = app.imastDropDown.Value;
            mainapp = app.camima;
            mainapp.selectedimast.(Imast) = app;
            app.LinkingLamp.Color = [0 1 0];
            app.imastDropDown.Enable = 'off';
            app.Status.Value = 'Successful Linking!';
            mainapp.Status.Value = 'one vuima successful linking in!';
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            fit(app);           
        end

        % Button pushed function: FittingSettingButton
        function FittingSettingButtonPushed(app, event)
            if ~isempty(app.Equation)&&~isempty(app.opt)
                fitset = Fitset;
                fitset.vuima = app;
                fitset.Equation.Value = app.Equation;
                fitset.Algorithm.Value = app.opt.Algorithm;
                fitset.DiffMaxChange.Value = app.opt.DiffMaxChange;
                fitset.DiffMinChange.Value = app.opt.DiffMinChange;
                
                coefficients = symvar(app.Equation{1});
                coefficients = coefficients(~strcmp(coefficients,'x'));
                coefficients = coefficients(~strcmp(coefficients,'y'));
                %create coTable
                StartPoint = num2cell(app.opt.StartPoint');
                Lower = num2cell(app.opt.Lower');
                Upper = num2cell(app.opt.Upper');
                %num2cell(true(7,1));
                fitset.CoTable.Enable = 'on';
                fitset.CoTable.Data = [coefficients StartPoint Lower Upper];
                
                fitset.MaxFunEvals.Value = app.opt.MaxFunEvals;
                fitset.MaxIter.Value = app.opt.MaxIter;
                fitset.Robust.Value = app.opt.Robust;
                fitset.TolFun.Value = app.opt.TolFun;
                fitset.TolX.Value = app.opt.TolX;
            else
                fitset = Fitset;
                fitset.vuima = app;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VuimaMain = uifigure;
            app.VuimaMain.Name = 'Vuima';
            app.VuimaMain.SizeChangedFcn = createCallbackFcn(app, @VuimaMainSizeChanged, true);
            setAutoResize(app, app.VuimaMain, true)
            app.MainTabGroup = uitabgroup(app.VuimaMain);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.SizeChangedFcn = createCallbackFcn(app, @HomeTabSizeChanged, true);
            app.HomeTab.Title = 'Home';
            app.HomeTab.Units = 'pixels';
            app.LinkingPanel = uipanel(app.HomeTab);
            app.LinkingPanel.Title = 'Linking';
            app.imastDropDownLabel = uilabel(app.LinkingPanel);
            app.imastDropDownLabel.Enable = 'off';
            app.imastDropDownLabel.Text = 'imast';
            app.imastDropDown = uidropdown(app.LinkingPanel);
            app.imastDropDown.Enable = 'off';
            app.LinkingLamp = uilamp(app.LinkingPanel);
            app.LinkButton = uibutton(app.LinkingPanel, 'push');
            app.LinkButton.ButtonPushedFcn = createCallbackFcn(app, @LinkButtonPushed, true);
            app.LinkButton.Enable = 'off';
            app.LinkButton.Text = 'Link';
            app.SavingPanel = uipanel(app.HomeTab);
            app.SavingPanel.Title = 'Saving';
            app.ViewTab = uitab(app.MainTabGroup);
            app.ViewTab.SizeChangedFcn = createCallbackFcn(app, @ViewTabSizeChanged, true);
            app.ViewTab.Title = 'View';
            app.ViewTab.Units = 'pixels';
            app.FilePanel = uipanel(app.ViewTab);
            app.FilePanel.Title = 'File';
            app.FolderEditFieldLabel = uilabel(app.FilePanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.FilePanel, 'text');
            app.BrowserButton = uibutton(app.FilePanel, 'push');
            app.BrowserButton.Text = 'Browser';
            app.FittingData = uitable(app.VuimaMain);
            app.FittingData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FittingData.RowName = {};
            app.Status = uieditfield(app.VuimaMain, 'text');
            app.FittingPanel = uipanel(app.VuimaMain);
            app.FittingPanel.SizeChangedFcn = createCallbackFcn(app, @FittingPanelSizeChanged, true);
            app.ImageShow = uiaxes(app.FittingPanel);
            app.fittingY = uiaxes(app.FittingPanel);
            app.fittingX = uiaxes(app.FittingPanel);
            app.FittingSettingPanel = uipanel(app.FittingPanel);
            app.Auto = uicheckbox(app.FittingSettingPanel);
            app.Auto.Text = 'Auto';
            app.FitButton = uibutton(app.FittingSettingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FittingModeDropDown = uidropdown(app.FittingSettingPanel);
            app.FittingModeDropDown.Items = {'Fitting Mode', 'Option 2', 'Option 3', 'Option 4'};
            app.FittingModeDropDown.Value = 'Fitting Mode';
            app.FittingSettingButton = uibutton(app.FittingSettingPanel, 'push');
            app.FittingSettingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingSettingButtonPushed, true);
            app.FittingSettingButton.Text = 'Setting';
            app.StopButton = uibutton(app.FittingSettingPanel, 'push');
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vuima()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VuimaMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VuimaMain)
        end
    end
end